classdef AcquisitionClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MenuFile                  matlab.ui.container.Menu
        Menu_ModifyDataDirectory  matlab.ui.container.Menu
        Menu_ViewDataList         matlab.ui.container.Menu
        Menu_BatchOrganizeData    matlab.ui.container.Menu
        MenuConfig                matlab.ui.container.Menu
        Menu_LoadConfig           matlab.ui.container.Menu
        Menu_ViewConfig           matlab.ui.container.Menu
        Menu_ModifyConfigMenu     matlab.ui.container.Menu
        Menu_DefaultConfigMenu    matlab.ui.container.Menu
        MenuHelp                  matlab.ui.container.Menu
        Menu_Manual               matlab.ui.container.Menu
        Menu_About                matlab.ui.container.Menu
        Label_AppName             matlab.ui.control.Label
        Image_Logo                matlab.ui.control.Image
        StatusTextArea            matlab.ui.control.TextArea
        Panel_1                   matlab.ui.container.Panel
        Button_Tcp                matlab.ui.control.StateButton
        Button_Impedances         matlab.ui.control.StateButton
        LocalIP                   matlab.ui.control.EditField
        IPLabel                   matlab.ui.control.Label
        ScanPort                  matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        Lamp_TcpReady             matlab.ui.control.Lamp
        Panel_2                   matlab.ui.container.Panel
        Button_Loaddata           matlab.ui.control.Button
        Button_FeatureAnalysis    matlab.ui.control.Button
        Button_TrainModel         matlab.ui.control.Button
        Button_EvaluationModel    matlab.ui.control.Button
        Button_ApplyModel         matlab.ui.control.StateButton
        Lamp_ModelReady           matlab.ui.control.Lamp
        ModelSelect               matlab.ui.control.DropDown
        EditField_T               matlab.ui.control.NumericEditField
        EditField_F1              matlab.ui.control.NumericEditField
        EditField_F2              matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        Button_Udp                matlab.ui.control.StateButton
        Label_Arrow               matlab.ui.control.Label
        RemotePort                matlab.ui.control.NumericEditField
        LocalPort                 matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        RemoteHost                matlab.ui.control.EditField
        IPLabel_2                 matlab.ui.control.Label
        Lamp_UdpReady             matlab.ui.control.Lamp
    end

    
    properties (Access = private)

        %================ 状态显示相关  ================= 
        statusHistory = {};           % 存储历史状态信息
        maxStatusLines = 500;         % 历史记录最大长度

        %================ 实验参数配置  ================= 
        ConfigMgr                     ConfigManager 

        %================ 数据文件管理  =================
        FileMgr                       FileManager

        %================ TCP/脑电采集 =================
        NSClient                      NeuroScanClient
        Host_IPAdr                    % 本地IP
        tcpTimer                      % TCP数据读取定时器
        DataBuf                       % 在线数据缓存

        %================ UDP通信/反馈 =================
        UDPClient                     UDPComm

        %================ 在线分类相关 =================        
        pendingFeedbacks              % 跟踪每个标签的反馈状态
        labelTable                    % 真实标签与预测标签对照表
        Accuracy                      % 在线实验分类准确率变化值
        classifyReady                 % 在线分类标志
        
        %================ 离线训练相关 =================
        tdata                         % 目标域数据
        tlabel                        % 目标域标签
        tInfo                         % 目标域数据元信息
        sdata                         % 源域数据
        slabel                        % 源域标签
        sInfo                         % 源域数据元信息
        Model                         % 训练好的模型
        modelInfo                     % 模型元信息
        modelReady = false            % 分类器准备状态
    end
    
    methods (Access = public)

        function check_IPAddress(app, value)
            flag=false;
            strlegal=true;

            %检查是否存在 0-9 和 . 以外的字符
            for i=1:length(value)
                if (value(i)>'9'||value(i)<'0')&&value(i)~='.'
                    uialert(app.UIFigure,{['存在非法字符' value(i)]; ...
                        '请重新输入'},'输入错误')
                    strlegal=false;
                    flag=true;
                    break;
                end
            end
            
            % 是否合乎标准
            if strlegal
                msg={'不是合法的IP地址格式（例如192.168.1.101）';'请重新输入'};
                %将IP地址4个字节拆开
                substr=regexp(value,'\.','split');
                %是否4个字节
                if length(substr)==4
                    for i=1:4
                        %是否存在空字符
                        if strcmp(substr{i},'')  
                            uialert(app.UIFigure,msg,'输入错误');
                            flag=true;
                        else
                            %数字是否超出范围
                            if str2double(substr{i})<0||str2double(substr{i})>255
                                uialert(app.UIFigure,msg,'输入错误');
                                flag=true;
                                break;
                            end
                        end
                    end
                else
                    uialert(app.UIFigure,msg,'输入错误');
                    flag=true;
                end
            end

            if flag
                if strcmpi(value,app.RemoteHost.Value)
                    app.RemoteHost.Value='192.168.1.101';
                elseif strcmpi(value,app.LocalIP.Value)
                    app.LocalIP.Value=app.Host_IPAdr;
                end
            end
        end
        
        % 更新状态显示
        function updateStatus(app, message, type)
            % 获取当前时间戳
            timestamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            
            % 确定消息类型和颜色
            if nargin < 3
                type = 'info';
            end

            % 颜色映射
            typeLabels = struct(...
                'info',    '实验信息', ...   % 信息
                'config',  '配置信息', ...   % 配置
                'success', '成功提醒', ...   % 正确
                'warning', '警告提醒', ...   % 警告
                'error',   '错误提醒', ...   % 错误
                'system',  '系统消息');      % 系统
            typeCell = typeLabels.(lower(type));
           
            % 创建带格式的消息行
            formattedLine = sprintf('[%s] %s | %s', timestamp, typeCell, message);

            % 添加到历史记录
            app.statusHistory{end+1} = formattedLine;
            
            % 限制历史记录长度
            if numel(app.statusHistory) > app.maxStatusLines
                app.statusHistory(1) = [];
            end
            
            % 更新文本区域显示
            app.StatusTextArea.Value = app.statusHistory;
            
            % 自动滚动到底部
            scroll(app.StatusTextArea, 'bottom');
            
            % 确保UI更新
            drawnow; 
           
        end
        
        % 错误处理
        function handleError(app, ME, context)
            % 打印错误原因
            fprintf('错误原因: %s\n', ME.message);

            % 提取错误位置信息
            stack = ME.stack;
            if ~isempty(stack)
                for i=length(stack):-1:1
                    lastFrame = stack(i);
                    if isempty(lastFrame.file)
                        location = '命令行';
                    else
                        location = sprintf('%s 第%d行', lastFrame.file, lastFrame.line);
                    end
                    fprintf('出错位置: %s\n', location);
                end
            else
                fprintf('无法定位错误位置\n');
            end

            % 生成错误消息
            errMsg = sprintf('%s: %s', context, ME.message);
            
            % 更新状态显示
            app.updateStatus(errMsg, 'error');
            
            % 可选：在对话框中显示详细错误
            uialert(app.UIFigure, errMsg, '错误', 'Icon', 'error');
        end

        function initDataBuffer(app)
            % 根据配置初始化数据缓存
            bufferPoints = app.ConfigMgr.getBufferPoints();
            
            app.DataBuf = struct(...
                'ChanNum', app.ConfigMgr.TotalChannels, ...
                'Flag', [0; 0], ... % 标签状态标志
                'eegdata', zeros(app.ConfigMgr.TotalChannels, bufferPoints), ...
                'writeIndex', 1, ...
                'samplesCollected', 0);
        end

        function initRecord(app)
            % 跟踪每个标签的反馈状态
            app.pendingFeedbacks = struct(...
                'labelTime', {}, ...      % 标签时刻
                'labelValue', {}, ...     % 标签值
                'rowIndex', {}, ...       % 标签表中的行索引
                'completedPoints', {} ... % 已完成的反馈
                );
            numFeedbacks = numel(app.ConfigMgr.TimePoints);
            app.labelTable = []; % 格式: [真实标签, 反馈1, 反馈2, ..., 反馈N]
            app.Accuracy = cell(1, numFeedbacks); % 每个反馈点的累计准确率
            app.classifyReady = false; %在线分类标志
        end

        function readTCPData(app)
            % 从TCP连接读取脑电数据
            [app.DataBuf, tempData] = EEGGetData(app.NSClient.Client, app.DataBuf);

            maxBufferPoints = app.ConfigMgr.getBufferPoints();
            
            if ~isempty(tempData)
                % 获取新数据点数
                newPoints = size(tempData, 2);
                
                % 更新环形缓存
                endIndex = app.DataBuf.writeIndex + newPoints - 1;
                
                if endIndex <= maxBufferPoints
                    % 数据未超出缓存边界
                    app.DataBuf.eegdata(:, app.DataBuf.writeIndex:endIndex) = tempData;
                else
                    % 处理缓存环绕
                    firstPart = maxBufferPoints - app.DataBuf.writeIndex + 1;
                    secondPart = newPoints - firstPart;
                    
                    app.DataBuf.eegdata(:, app.DataBuf.writeIndex:end) = tempData(:, 1:firstPart);
                    app.DataBuf.eegdata(:, 1:secondPart) = tempData(:, firstPart+1:end);
                end
                
                % 更新写入位置
                app.DataBuf.writeIndex = mod(app.DataBuf.writeIndex + newPoints - 1, maxBufferPoints) + 1;
                app.DataBuf.samplesCollected = app.DataBuf.samplesCollected + newPoints;
                
                % 保存数据到文件
                app.FileMgr.saveData(tempData);
                
                % 检测标签并处理
                app.detectAndProcessLabels(tempData);
            end
        end

        function detectAndProcessLabels(app, newData)
            % 获取标签通道索引
            labelChannel = app.ConfigMgr.LabelChannel;
            
            % 检查新数据中的标签通道
            labelData = newData(labelChannel, :);
            labelIndices = find(labelData ~= 0);
            
            if ~isempty(labelIndices)
                % 获取最后一个有效标签的位置
                lastLabelPos = labelIndices(end);
                lastLabelValue = labelData(lastLabelPos);
                
                % 计算在全局缓存中的位置
                globalLabelPos = app.DataBuf.samplesCollected - size(newData, 2) + lastLabelPos;
                
                % 检测到新标签时，初始化标签行
                numFeedbacks = numel(app.ConfigMgr.TimePoints);
                newRow = NaN(1, numFeedbacks + 1);
                newRow(1) = lastLabelValue; % 第一列是真实标签
                
                % 添加到标签表
                app.labelTable = [app.labelTable; newRow];
                rowIndex = size(app.labelTable, 1);
                
                % 检测到新标签时，初始化反馈状态
                newFeedback = struct(...
                    'labelTime', globalLabelPos, ...
                    'labelValue', lastLabelValue, ...
                    'rowIndex', rowIndex, ... % 记录在标签表中的行索引
                    'completedPoints', [] ... % 初始无完成点
                );
                
                % 添加到待处理队列
                app.pendingFeedbacks(end+1) = newFeedback;
                
                % 显示状态
                app.updateStatus(sprintf('检测到标签: %d', lastLabelValue), 'info');
                app.classifyReady = true; % 设置新标签就绪标志
            end
            
            % 处理所有待反馈项
            if ~isempty(app.pendingFeedbacks) && app.modelReady
                currentSamples = app.DataBuf.samplesCollected;
                feedbackPoints = app.ConfigMgr.getFeedbackPoints();
                feedbackTimes = app.ConfigMgr.TimePoints;
                
                % 获取最新标签的索引
                latestIndex = numel(app.pendingFeedbacks);
                
                % 遍历所有待处理标签
                validIndices = true(1, numel(app.pendingFeedbacks));
                for i = 1:numel(app.pendingFeedbacks)
                    fb = app.pendingFeedbacks(i);
                    
                    % 计算标签后已收集的样本数
                    collectedAfterLabel = currentSamples - fb.labelTime;
                    
                    % 检查所有反馈时间点
                    for j = 1:numel(feedbackPoints)
                        requiredPoints = feedbackPoints(j);
                        timeSec = feedbackTimes(j); % 当前时间点
                        
                        % 如果达到时间点且未完成
                        if collectedAfterLabel >= requiredPoints && ...
                           ~ismember(j, fb.completedPoints)
                            
                            try
                                % 提取时间窗数据
                                segment = app.extractTimeWindow(fb.labelTime, requiredPoints);
                                
                                % 执行分类
                                predlabel = app.runClassification(segment, timeSec);
                                
                                % 更新标签表（所有标签都需要更新）
                                app.labelTable(fb.rowIndex, j+1) = predlabel;
                                
                                % 更新状态
                                classInfo = sprintf('%.1fs分类: %d→%d', ...
                                    timeSec, fb.labelValue, predlabel);
                                app.updateStatus(classInfo, 'info');
                                
                                % 只有最新标签且分类就绪时才发送反馈
                                if app.classifyReady && i == latestIndex
                                    app.sendClassificationResult([j, fb.labelValue, predlabel]);
                                    
                                    % 如果是最后一个时间点，标记分类完成
                                    if j == numel(feedbackPoints)
                                        app.classifyReady = false;
                                    end
                                end

                                % 标记该时间点已完成
                                app.pendingFeedbacks(i).completedPoints = ...
                                    [fb.completedPoints, j];
                                
                                % 更新准确率
                                app.calculateAccuracy(j);
                                
                            catch ME
                                app.handleError(ME, '在线数据处理出现错误');
                            end
                        end
                    end
                    
                    % 检查是否所有反馈都已完成
                    if numel(app.pendingFeedbacks(i).completedPoints) == numel(feedbackPoints)
                        validIndices(i) = false; % 标记为可移除
                    end
                end
                
                % 移除已完成所有反馈的项
                app.pendingFeedbacks = app.pendingFeedbacks(validIndices);
            end
        end
        
        function segment = extractTimeWindow(app, labelTime, endOffset)
            % 参数说明:
            %   labelTime: 标签时刻(样本索引)
            %   endOffset: 标签后结束点的偏移量(样本数)
            % 获取时间窗对应的点数

            % 计算时间窗的起点和终点
            startPoint = labelTime;
            endPoint = labelTime + endOffset-1;
            
            % 验证时间窗是否在缓存范围内
            if startPoint < 1 || endPoint > app.DataBuf.samplesCollected
                app.updateStatus('要求的数据量超出限制','error');
                return;
            end
            
            % 转换为环形缓存索引
            bufferSize = size(app.DataBuf.eegdata, 2);
            startIndex = mod(startPoint - 1, bufferSize) + 1;
            endIndex = mod(endPoint - 1, bufferSize) + 1;
            
            % 提取数据段
            if endIndex >= startIndex
                segment = app.DataBuf.eegdata(:, startIndex:endIndex);
            else
                % 处理缓存环绕
                part1 = app.DataBuf.eegdata(:, startIndex:end);
                part2 = app.DataBuf.eegdata(:, 1:endIndex);
                segment = [part1, part2];
            end
            
            % 移除标签通道
            segment(app.ConfigMgr.LabelChannel, :) = [];
        end

        function result = runClassification(app, segment, timePoint)
            % 此处可替换为实际分类算法流程。
            % segment为Nc*Nt的实时脑电信号片段：
            % Nc的大小为配置信息中的脑电通道数（不含标签通道），
            % Nt为配置信息中的时间窗宽*采样率

            % 实际实现中，你可能需要以下步骤：
            % 1. 预处理（降采样、时间窗截取、滤波等）
            % 2. 特征提取
            % 3. 应用训练好的分类模型
                       
            % 生成安全的字段名（替换小数点为下划线）
            fieldName = sprintf('model_%.1fs', timePoint);
            fieldName = strrep(fieldName, '.', '_'); % 替换小数点为下划线

            % 根据反馈时间点选择对应模型
            if isfield(app.Model, fieldName)
                model = app.Model.(fieldName);
            else
                msg = sprintf('未找到%.1fs对应的模型', timePoint);
                app.updateStatus(msg,'error');
                result = 0;
                return;
            end
            
            % 分类
            try
                result = online_classify(model, segment);
            catch ME
                result = 0;
                app.handleError(ME, '分类失败');
            end
            
        end

        function sendClassificationResult(app, result)
            % 确保反馈通道开启
            
            if app.UDPClient.IsConnected
                try
                    % 发送分类结果至反馈端
                    app.UDPClient.sendData(result);

                    % 在UI上显示发送结果
                    app.updateStatus('发送分类结果成功!','success')

                catch ME
                    % 错误处理
                    app.handleError(ME, '发送分类结果失败');
                end
            else
                app.Button_Udp.Value = false;
                app.UDPClient.cleanup();
                app.updateStatus('反馈端连接未开启!','system')
            end
        end

        function sendClassificationResult2(app, result)
            % 确保反馈通道开启
            if app.UDPClient.IsConnected
                try
                    % 发送分类结果至反馈端
                    app.UDPClient.sendData(result);

                    % 在UI上显示发送结果
                    app.updateStatus('发送分类结果成功!','success')

                catch ME
                    % 错误处理
                    app.handleError(ME, '发送分类结果失败');
                end
            else
                app.Button_Udp.Value = false;
                app.UDPClient.cleanup();
                app.updateStatus('反馈端连接未开启!','system')
            end
        end

        function calculateAccuracy(app, feedbackIndex)
            % 计算指定反馈点的累计准确率
            if isempty(app.labelTable) || size(app.labelTable, 1) < 1
                return;
            end

            % 提取真实标签和当前反馈点的预测标签
            trueLabels = app.labelTable(:, 1);
            predLabels = app.labelTable(:, feedbackIndex+1); % +1 因为第一列是真实标签

            % 移除尚未处理的条目 (NaN值)
            validIdx = ~isnan(predLabels);
            trueLabels = trueLabels(validIdx);
            predLabels = predLabels(validIdx);

            if isempty(trueLabels)
                return;
            end

            % 计算当前准确率
            currentAccuracy = mean(trueLabels == predLabels) * 100;

            % 更新累计准确率
            if numel(app.Accuracy{feedbackIndex}) < numel(trueLabels)
                % 扩展准确率数组
                app.Accuracy{feedbackIndex}(end+1:numel(trueLabels)) = currentAccuracy;
            else
                % 更新最后一个值
                app.Accuracy{feedbackIndex}(end) = currentAccuracy;
            end

            % 更新状态
            timeSec = app.ConfigMgr.TimePoints(feedbackIndex);
            msg = sprintf('[%.1fs] 样本数: %d, 准确率: %.1f%%', ...
                timeSec, numel(trueLabels), currentAccuracy);
            app.updateStatus(msg);
        end

        function isIncompatible = check_model_compatibility(app)
            isIncompatible = false;
            
            % 检查模型反馈时间点与当前配置是否匹配
            if ~isequal(app.modelInfo.FeedbackTimes, app.ConfigMgr.TimePoints)
                timeStrsModel = arrayfun(@(t) sprintf('%.1fs', t), ...
                    app.modelInfo.FeedbackTimes, 'UniformOutput', false);
                timeStrsConfig = arrayfun(@(t) sprintf('%.1fs', t), ...
                    app.ConfigMgr.TimePoints, 'UniformOutput', false);
                
                msg = sprintf(['模型时间点 (%s) 与当前配置 (%s) 不匹配。', ...
                    '请调整配置或加载匹配的模型。'], ...
                    strjoin(timeStrsModel, ', '), strjoin(timeStrsConfig, ', '));
                
                uialert(app.UIFigure, msg, '模型不兼容', 'Icon', 'warning');
                app.Button_ApplyModel.Value = 0;
                isIncompatible = true;
                return;
            end

            % 检查模型的采样率信息
            if ~isequal(app.modelInfo.SamplingRate,app.ConfigMgr.SamplingRate)
                errordlg({'分类模型中的数据原始采样率', ...
                    '与实验配置中的不符！'},'模型与配置不符')
                isIncompatible = true;
            end

            if isIncompatible
                msg='分类模型与实验配置兼容性检查不通过!';
                app.updateStatus(msg,'error')
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %添加辅助函数到工作路径
            fullpath = mfilename('fullpath');
            path=fileparts(fullpath);
            addpath(genpath(path));
            
            % 自动切换到脚本所在目录
            cd(path);

            %显示logo
            app.UIFigure.Icon='Resources\Icons\app_icon_2.png';
            app.Image_Logo.ImageSource='Resources\Icons\logo.png';

            % 获取本机IP地址
            address = java.net.InetAddress.getLocalHost;
            app.Host_IPAdr = char(address.getHostAddress);
            app.LocalIP.Value=app.Host_IPAdr;

            % 初始化实验配置
            app.ConfigMgr = ConfigManager();

            % 初始化文件管理器
            app.FileMgr   = FileManager(app.ConfigMgr);

            % 初始化数据缓存
            app.initDataBuffer();

            % 创建 NeuroScanClient 对象
            ChanNum = app.ConfigMgr.TotalChannels;
            app.NSClient  = NeuroScanClient(ChanNum);

            % 创建 UDP 端口对象
            app.UDPClient = UDPComm();

            % 初始化状态显示
            app.updateStatus(['欢迎使用',app.Label_AppName.Text,'!'], 'system');
            app.updateStatus('已加载默认配置', 'system');

            % strList = app.ConfigMgr.getConfigString();
            % for i=1:length(strList)
            %     app.updateStatus(strList{i},'config')
            % end
            app.updateStatus('开始实验前请务必检查和修改配置信息！', 'system');

        end

        % Value changed function: Button_Tcp
        function Button_TcpValueChanged(app, event)
            value = app.Button_Tcp.Value;

            %启动/停止信号采集
            if value
              
                % 启动 NeuroScanClient TCP 连接
                try
                    hostIP = app.LocalIP.Value;         % 本地 IP 
                    portNum = app.ScanPort.Value;       % Scan端口号
                    app.NSClient.startAcq(hostIP, portNum);
                catch ME
                    app.handleError(ME, 'TCP 连接连接失败')
                    app.Button_Tcp.Value = false;
                    return;
                end

                % 重新初始化数据缓存
                app.FileMgr.initDataBuffer(app.ConfigMgr);
                app.initDataBuffer();
                
                % 创建数据文件
                app.FileMgr.createDataFile();

                % 初始化实验结果记录(开始实验时执行)
                app.initRecord();

                % 创建并启动定时器，每 40ms 读取一次数据
                app.tcpTimer = timer(...
                    'TimerFcn', @(~,~) app.readTCPData(), ...
                    'Period', 0.2, ... % 25Hz ≈ 40ms，匹配Scan4.5数据包速率
                    'ExecutionMode', 'fixedSpacing', ...
                    'Name', 'TCPDataReader');
                start(app.tcpTimer);

                % 更改显示状态
                app.Lamp_TcpReady.Color=[0,1,0];
                app.Button_Tcp.Text='关闭连接';
                
                % 禁用相关控件
                app.LocalIP.Enable='off';
                app.ScanPort.Enable='off';
                app.Button_Impedances.Enable='off';
                app.MenuFile.Enable='off';
                app.Menu_LoadConfig.Enable='off';
                app.Menu_ModifyConfigMenu.Enable='off';
                app.Menu_DefaultConfigMenu.Enable='off';

                app.updateStatus('信号采集已开启!','success')
            else
                % 停止定时器
                if ~isempty(app.tcpTimer) && isvalid(app.tcpTimer)
                    stop(app.tcpTimer);
                    delete(app.tcpTimer);
                end

                % 关闭TCP连接
                app.NSClient.stopAcq();
                
                % 更改显示状态
                app.Lamp_TcpReady.Color=[1,0,0];
                app.Button_Tcp.Text='开启连接';

                % 启用相关控件
                app.LocalIP.Enable='on';
                app.ScanPort.Enable='on';
                app.Button_Impedances.Enable='on';
                app.MenuFile.Enable='on';
                app.Menu_LoadConfig.Enable='on';
                app.Menu_ModifyConfigMenu.Enable='on';
                app.Menu_DefaultConfigMenu.Enable='on';
                
                app.updateStatus('信号采集已关闭!','system')

                % 保存实验结果(结束实验时执行)
                if ~isempty(app.labelTable) && ~any(cellfun(@isempty, app.Accuracy))
                    % 准备每个反馈点的最终准确率
                    finalAccuracies = cellfun(@(x) x(end), app.Accuracy);
                    
                    % 保存结果
                    fullPath = app.FileMgr.saveResult(app.labelTable, finalAccuracies);
                    app.updateStatus(sprintf('实验结果已保存至%s',fullPath),'info');
                    
                    % 生成状态消息
                    msg = '最终分类准确率:';
                    for i = 1:numel(finalAccuracies)
                        timeSec = app.ConfigMgr.TimePoints(i);
                        msg = sprintf('%s\n  %.1fs: %.1f%%', msg, timeSec, finalAccuracies(i));
                    end
                    app.updateStatus(msg,'info');
                end

                % 关闭数据文件
                app.FileMgr.closeDataFile();
            end
        end

        % Value changed function: Button_Udp
        function Button_UdpValueChanged(app, event)
            value = app.Button_Udp.Value;

            % 启动/停止反馈输出
            if value
                try
                    % 配置远程主机和端口
                    remoteHost   = app.RemoteHost.Value;   % 远程端IP
                    remotePort = app.RemotePort.Value;     % 远程端Port
                    localPort  = app.LocalPort.Value;      % 本地端Port
                    
                    % 启动 UDP 端口信息传输
                    app.UDPClient.start( ...
                        remoteHost, ...
                        remotePort, ...
                        localPort ...                            
                    );

                    pause(2)
                    if app.UDPClient.IsConnected
                        app.updateStatus("反馈端连接成功！",'system')
                    else
                        app.updateStatus("请先开启反馈端UDP连接！",'system')
                        app.Button_Udp.Value = false;
                        return;
                    end
                    
                catch ME
                    app.handleError(ME, '反馈端连接失败');
                    app.Button_Udp.Value = false;
                    app.UDPClient.stop();
                    return;
                end

                % 更改显示状态
                app.Lamp_UdpReady.Color = [0,1,0];
                app.Button_Udp.Text='关闭连接';

                % 禁用相关控件
                app.RemoteHost.Enable='off';
                app.LocalPort.Enable='off';
                app.RemotePort.Enable='off';

            else
                % 停止UDP通信
                app.UDPClient.stop();
                
                % 更改显示状态
                app.Lamp_UdpReady.Color = [1,0,0];
                app.Button_Udp.Text='开启连接';

                % 启用相关控件
                app.RemoteHost.Enable='on';
                app.LocalPort.Enable='on';
                app.RemotePort.Enable='on';
                
                app.updateStatus('反馈输出已关闭!','system')
            end
        end

        % Button pushed function: Button_Loaddata
        function Button_LoaddataPushed(app, event)
            % 创建选择数据类型的对话框
            selection = uiconfirm(app.UIFigure, '选择数据类型:', '加载数据', ...
                'Options', {'目标域数据', '源域数据', '取消'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            
            % 用户取消选择
            if strcmp(selection, '取消')
                return;
            end

            % 选择文件 (支持多选)
            [files, path] = uigetfile(...
                {'*.cnt;*.mat', 'EEG数据文件 (*.cnt, *.mat)'; ...
                '*.cnt', 'EEG原始数据 (*.cnt)'; ...
                '*.mat', 'MATLAB数据文件 (*.mat)'}, ...
                '选择数据文件', 'MultiSelect', 'on');
            
            % 用户取消选择
            if isequal(files, 0)
                return; 
            end

            % 统一文件格式
            if ischar(files)
                % 单个文件
                filePaths = {fullfile(path, files)}; 
            else
                % 多个文件
                filePaths = cellfun(@(f) fullfile(path, f), files, ...
                    'UniformOutput', false);
            end

            % 验证文件类型
            isCnt = cellfun(@(f) endsWith(f, '.cnt', 'IgnoreCase', true), ...
                filePaths);
            isMat = cellfun(@(f) endsWith(f, '.mat', 'IgnoreCase', true), ...
                filePaths);

            % 处理混合文件类型错误
            if any(isCnt) && any(isMat)
                uialert(app.UIFigure, '不能同时选择 .cnt 和 .mat 文件', ...
                    '文件类型错误');
                return;
            end

            % 创建进度对话框并设置自动清理
            progressDlg = uiprogressdlg(app.UIFigure, ...
                'Title', '数据加载中', ...
                'Message', '请耐心等待...', ...
                'Indeterminate', 'on');
            cleanupDlg = onCleanup(@() close(progressDlg));

            try
                % 处理 CNT 文件
                if any(isCnt)
                    % 使用新函数加载 CNT 文件
                    [data, label, Info] = loadCntFiles(filePaths);

                    % 自动保存为 MAT 文件
                    [~, firstFile] = fileparts(filePaths{1});
                    savePath = fullfile(path, [firstFile '_merged.mat']);

                    % 保存为v7格式方便其它程序(如python)读取
                    save(savePath, 'data', 'label', 'Info', '-v7');

                    app.updateStatus(sprintf('CNT数据已保存至: %s', savePath), 'success');

                    % 处理 MAT 文件
                elseif any(isMat)
                    % 仅支持单个 MAT 文件
                    if numel(filePaths) > 1
                        uialert(app.UIFigure, '请选择单个 MAT 文件', '输入错误');
                        return;
                    end

                    matData = load(filePaths{1});

                    % 验证 MAT 文件内容
                    requiredFields = {'data', 'label', 'Info'};
                    if ~all(isfield(matData, requiredFields))
                        missing = setdiff(requiredFields, fieldnames(matData));
                        msg=sprintf('MAT文件缺少字段: %s', strjoin(missing, ', '));
                        uialert(app.UIFigure,msg,'数据集加载失败')
                    end

                    data = matData.data;
                    label = matData.label;
                    Info = matData.Info;

                end

                % 根据选择存储数据
                if strcmp(selection, '目标域数据')
                    app.tdata = data;
                    app.tlabel = label;
                    app.tInfo = Info;
                elseif strcmp(selection, '源域数据')
                    app.sdata = data;
                    app.slabel = label;
                    app.sInfo = Info;
                end

                % 获取数据维度
                [channels, timepoints, samples] = size(data); 
                [types, ~, idx] = unique(label);
                counts = accumarray(idx, 1);

                % 生成格式化字符串
                label_strs = arrayfun(@(x,y) sprintf('类别%d:%d', x, y), ...
                    types, counts, 'UniformOutput', false);
                categories_str = strjoin(label_strs, ', ');

                % 组合最终消息并显示
                msg = sprintf('%s加载成功! 数据大小为:%d×%d×%d, 各类别样本数分别为: %s', ...
                    selection, channels, timepoints, samples, categories_str);
                app.updateStatus(msg, 'success');
            catch ME
                app.handleError(ME, '加载错误');
            end

        end

        % Button pushed function: Button_TrainModel
        function Button_TrainModelPushed(app, event)
            % 创建训练类型选择对话框
            selection = uiconfirm(app.UIFigure, '选择训练类型:', '模型训练', ...
                'Options', {'标准模型训练', '迁移模型训练', '取消'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            
            if strcmp(selection, '取消')
                return;
            end

            % 重新初始化数据缓存
            app.FileMgr.initDataBuffer(app.ConfigMgr);
            
            % 获取算法和参数
            alg = app.ModelSelect.Value;
            freqs = [app.EditField_F1.Value, app.EditField_F2.Value];
            timeoffset = app.EditField_T.Value;% 对比标签时刻的时间偏移量
            feedbackTimes = app.ConfigMgr.TimePoints; % 获取所有反馈时间点
            
            % 创建进度对话框并设置自动清理
            progressDlg = uiprogressdlg(app.UIFigure, ...
                'Title', '模型训练中', ...
                'Message', '请耐心等待...', ...
                'Indeterminate', 'on');
            cleanupDlg = onCleanup(@() close(progressDlg));

            try  
                if strcmp(selection, '标准模型训练')
                    % 检查算法适用性
                    if strcmpi(alg,'RSFDA')
                        uialert(app.UIFigure,'不允许使用RSFDA算法训练标准模型', ...
                            '算法不合法')
                        return;
                    end

                    % 检查数据可用性
                    if ~isempty(app.tdata)
                        data = app.tdata(:,app.tInfo.taskpoints,:);
                        label = app.tlabel;
                        fs = app.tInfo.fs;
                    elseif ~isempty(app.sdata)
                        data = app.sdata(:,app.sInfo.taskpoints,:);
                        label = app.slabel;
                        fs = app.sInfo.fs;
                    else
                        uialert(app.UIFigure,'请先加载目标域或源域数据','数据集缺失')
                        return;
                    end

                    % 初始化模型存储结构
                    models = struct();
                    
                    % 为每个反馈时间点训练模型
                    for i = 1:numel(feedbackTimes)
                        timePoint = feedbackTimes(i);
                        times = [timeoffset, timePoint]; % 时间窗从偏移值到当前反馈点
                        
                        % 设置字段名
                        fieldName = sprintf('model_%.1fs', timePoint);
                        fieldName = strrep(fieldName, '.', '_'); % 替换小数点为下划线

                        % 执行标准训练
                        models.(fieldName) = ...
                            model_training(data, label, alg, fs, freqs, times);
                        
                        app.updateStatus(sprintf('%.1fs模型训练完成', timePoint), 'info');
                    end
                    msg = '标准模型训练完成!';
                    
                else % 迁移模型训练
                    if isempty(app.sdata) || isempty(app.tdata)
                        uialert(app.UIFigure,'迁移训练需要源域和目标域数据','数据集缺失')
                        return;
                    else
                        tdata_ = app.tdata(:,app.tInfo.taskpoints,:);
                        sdata_ = app.sdata(:,app.sInfo.taskpoints,:);
                        fs = app.tInfo.fs;
                    end

                    if ~isequal(app.sInfo.fs, app.tInfo.fs)
                        uialert(app.UIFigure, sprintf(['元信息不一致:\n源域:' ...
                            '%d Hz\n目标域: %d Hz'], ...
                            app.tInfo.fs), '数据不匹配');
                        return;
                    end
                    
                    % 为每个反馈时间点训练模型
                    for i = 1:numel(feedbackTimes)
                        timePoint = feedbackTimes(i);
                        times = [timeoffset, timePoint]; % 时间窗从偏移值到当前反馈点

                        % 设置字段名
                        fieldName = sprintf('model_%.1fs', timePoint);
                        fieldName = strrep(fieldName, '.', '_'); % 替换小数点为下划线
                        
                        % 执行迁移训练
                        models.(fieldName) = ...
                            tlmodel_training(sdata_, app.slabel, ...
                            tdata_, app.tlabel, alg, fs, freqs, times);
                        
                        app.updateStatus(sprintf('%.1fs迁移模型训练完成', timePoint), 'info');
                    end
                    msg = '迁移模型训练完成!';
                end

                % 更新当前模型
                app.Model = models;

                uialert(app.UIFigure, msg, '成功', 'Icon', 'success');

                % 保存模型集合
                try
                    app.modelInfo = app.FileMgr.saveModel(models);
                    app.updateStatus(sprintf('分类模型成功保存到%s文件', ...
                        app.modelInfo.filePath), 'success');
                catch ME
                    app.updateStatus(sprintf('模型保存失败: %s', ME.message), 'error');
                    app.handleError(ME, '模型保存失败');
                end

            catch ME
                app.handleError(ME,'模型训练过程出错');
            end

        end

        % Button pushed function: Button_FeatureAnalysis
        function Button_FeatureAnalysisPushed(app, event)
            % 检查数据可用性
            if isempty(app.tdata) && isempty(app.sdata)
                uialert(app.UIFigure, '请先加载EEG数据', '数据缺失');
                return;
            end

            % 创建分析类型选择对话框
            selection = uiconfirm(app.UIFigure, '选择特征分析类型:', '特征分析', ...
                'Options', {'常规特征分析', '跨域特征分析', '取消'}, ...
                'DefaultOption', 1, 'CancelOption', 3);

            if strcmp(selection, '取消')
                return;
            end

            % 重新初始化数据缓存
            app.FileMgr.initDataBuffer(app.ConfigMgr);

            % 创建进度对话框并设置自动清理
            progressDlg = uiprogressdlg(app.UIFigure, ...
                'Title', '特征分析中', ...
                'Message', '请耐心等待...', ...
                'Indeterminate', 'on');
            cleanupDlg = onCleanup(@() close(progressDlg));

            try
                % 执行分析
                if strcmp(selection, '常规特征分析')
                    % 使用目标域数据
                    if ~isempty(app.tdata)
                        data = app.tdata;
                        label = app.tlabel;
                        Info = app.tInfo;
                        domain = '目标域';
                    elseif ~isempty(app.sdata)
                        data = app.sdata;
                        label = app.slabel;
                        Info = app.sInfo;
                        domain = '源域';
                    else
                        uialert(app.UIFigure,'请先加载目标域或源域数据','数据集缺失')
                        return;
                    end

                    % 常规特征分析
                    results = routineFeatureAnalysis(data, label, Info, domain);

                else % 跨域特征分析
                    if isempty(app.sdata) || isempty(app.tdata)
                        uialert(app.UIFigure,'跨域分析需要源域和目标域数据','数据集缺失')
                        return;
                    end
                    if ~isequal(app.sInfo.period, app.tInfo.period) || ...
                            ~isequal(app.sInfo.fs, app.tInfo.fs)
                        uialert(app.UIFigure, sprintf(['元信息不一致:\n源域: %d时间窗, ' ...
                            '%dHz\n目标域: %d时间窗, %dHz'], ...
                            numel(app.sInfo.period), app.sInfo.fs, ...
                            numel(app.tInfo.period), app.tInfo.fs), '数据不匹配');
                        return;
                    end
            
                    % 跨域特征分析
                    results = crossDomainFeatureAnalysis(...
                        app.sdata, app.slabel, app.tdata, app.tlabel, app.tInfo);
                end
                
                % 保存分析结果
                try
                    filePath = app.FileMgr.saveAnalysisResult(results);
                    app.updateStatus(sprintf('特征分析结果已保存至: %s', filePath), 'success');
                catch ME
                    app.handleError(ME,'特征分析结果保存失败');
                    app.updateStatus(sprintf('特征分析结果保存失败: %s', ME.message), 'error');
                end

                % 显示分析结果
                displayFeatureResults(results);
                
            catch ME
                app.handleError(ME, '特征分析出现错误');
            end
        end

        % Button pushed function: Button_EvaluationModel
        function Button_EvaluationModelPushed(app, event)
            % 检查数据可用性
            if isempty(app.tdata) && isempty(app.sdata)
                uialert(app.UIFigure, '请先加载目标域或源域数据', '数据缺失');
                return;
            end

            % 创建评估选项对话框
            selection = uiconfirm(app.UIFigure, '选择评估模式:', '模型评估', ...
                'Options', {'标准模型评估', '迁移模型评估', '取消'}, ...
                'DefaultOption', 1, 'CancelOption', 3);

            if strcmp(selection, '取消')
                return;
            end

            % 重新初始化数据缓存
            app.FileMgr.initDataBuffer(app.ConfigMgr);

            % 获取算法和参数
            alg = app.ModelSelect.Value;
            freqs = [app.EditField_F1.Value, app.EditField_F2.Value];
            timeoffset = app.EditField_T.Value;% 对比标签时刻的时间偏移量
            feedbackTimes = app.ConfigMgr.TimePoints;
            timeset=[repmat(timeoffset, numel(feedbackTimes), 1), ...
                feedbackTimes(:)];
            
            % 创建进度对话框并设置自动清理
            progressDlg = uiprogressdlg(app.UIFigure, ...
                'Title', '模型评估中', ...
                'Message', '请耐心等待...', ...
                'Indeterminate', 'on');
            cleanupDlg = onCleanup(@() close(progressDlg));

            try
                % 执行评估
                if strcmp(selection, '标准模型评估')
                    % 检查算法适用性
                    if strcmpi(alg,'RSFDA')
                        uialert(app.UIFigure,'不允许使用RSFDA算法评估标准模型', ...
                            '算法不合法')
                        return;
                    end

                    % 检查数据可用性
                    if ~isempty(app.tdata)
                        data = app.tdata(:,app.tInfo.taskpoints,:);
                        label = app.tlabel;
                        fs = app.tInfo.fs;
                    elseif ~isempty(app.sdata)
                        data = app.sdata(:,app.sInfo.taskpoints,:);
                        label = app.slabel;
                        fs = app.sInfo.fs;
                    else
                        uialert(app.UIFigure,'请先加载目标域或源域数据','数据集缺失')
                        return;
                    end

                    results = evaluate_standard_model(data, label, alg, ...
                        fs, freqs, timeset);

                else % 迁移模型评估
                    if isempty(app.sdata) || isempty(app.tdata)
                        uialert(app.UIFigure,'迁移评估需要源域和目标域数据','数据集缺失')
                        return;
                    else
                        tdata_ = app.tdata(:,app.tInfo.taskpoints,:);
                        sdata_ = app.sdata(:,app.sInfo.taskpoints,:);
                        fs = app.tInfo.fs;
                    end

                    if ~isequal(app.sInfo.fs, app.tInfo.fs)
                        uialert(app.UIFigure, sprintf(['元信息不一致:\n源域:' ...
                            '%d Hz\n目标域: %d Hz'], ...
                            app.tInfo.fs), '数据不匹配');
                        return;
                    end

                    results = evaluate_transfer_model(...
                        sdata_, app.slabel, tdata_, app.tlabel, ...
                        alg, fs, freqs, timeset);
                end

                % 关闭进度对话框
                close(progressDlg);

                % 显示评估结果
                showEvaluationResults(results);

                % 保存评估结果
                try                   
                    filePath = app.FileMgr.saveEvaluationResult(results);
                    app.updateStatus(sprintf('评估结果已保存至: %s', filePath), 'success');
                catch ME
                    app.handleError(ME, '评估结果保存失败');
                    app.updateStatus(sprintf('评估结果保存失败: %s', ME.message), 'error');
                end

            catch ME
                app.handleError(ME, '模型评估出现错误');
            end
        end

        % Value changed function: Button_ApplyModel
        function Button_ApplyModelValueChanged(app, event)
            value = app.Button_ApplyModel.Value;

            if value % "开启"状态
                % 检查模型是否存在
                if isempty(app.Model)
                    selection = uiconfirm(app.UIFigure, ...
                        '未找到模型，请先训练模型或从本地加载模型:', '模型部署', ...
                        'Options', {'加载模型', '返回'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
                    
                    if strcmp(selection, '加载模型')
                        [file, path] = uigetfile('*.mat', '选择模型文件');
                        if ~isequal(file, 0)
                            try
                                modelData = load(fullfile(path, file));
                                
                                % 检查是否为多时间点模型集合
                                if isfield(modelData, 'models') && isfield(modelData, 'modelInfo')
                                    app.Model = modelData.models;
                                    app.modelInfo = modelData.modelInfo;

                                    % 验证所有模型字段是否存在
                                    feedbackTimes = app.modelInfo.FeedbackTimes;
                                    for i = 1:numel(feedbackTimes)
                                        timePoint = feedbackTimes(i);
                                        fieldName = sprintf('model_%.1fs', timePoint);
                                        fieldName = strrep(fieldName, '.', '_');
                                        
                                        if ~isfield(app.Model, fieldName)
                                            msg=sprintf('模型集合缺少%.1fs模型', timePoint);
                                            uialert(app.UIFigure,msg,'加载的模型缺少必要成分')
                                            return;
                                        end
                                    end
                                    
                                    % 更新配置管理器中的反馈时间点
                                    if ~isequal(app.ConfigMgr.TimePoints,app.modelInfo.FeedbackTimes)
                                        app.ConfigMgr.TimePoints = app.modelInfo.FeedbackTimes;
                                        app.updateStatus('模型与配置中的反馈时间点设置不一致！','warning')
                                        app.updateStatus('配置中的反馈时间点参数已匹配至模型中的反馈时间点！','system')
                                    end
                                    
                                    % 更新状态
                                    timeStrs = arrayfun(@(t) sprintf('%.1fs', t), ...
                                        app.modelInfo.FeedbackTimes, 'UniformOutput', false);
                                    timeList = strjoin(timeStrs, ', ');
                                    app.updateStatus(sprintf('加载模型集合成功: %d个模型 (%s)', ...
                                        app.modelInfo.ModelCount, timeList), 'success');
                                    
                                else
                                    app.Button_ApplyModel.Value=0;
                                    uialert(app.UIFigure,'无效的模型文件格式','模型加载失败');
                                    return;
                                end
                            catch ME
                                app.Model=[];
                                app.modelInfo=[];
                                app.Button_ApplyModel.Value=0;
                                app.handleError(ME,'模型加载失败');
                                return;
                            end
                        else
                            app.Button_ApplyModel.Value=0;
                            return; % 用户取消
                        end
                    else
                        app.Button_ApplyModel.Value=0;
                        return; % 用户选择返回
                    end
                end

                % 检查分类模型是否与实验配置兼容
                if app.check_model_compatibility()
                    app.Button_ApplyModel.Value=0;
                    return;
                end

                % 更新模型准备状态
                app.modelReady = true;
                
                % 更改显示状态
                app.Lamp_ModelReady.Color = [0, 1, 0]; % 绿色
                app.Button_ApplyModel.Text='取消部署';
                
                % 显示加载的模型信息
                timeStrs = arrayfun(@(t) sprintf('%.1fs', t), ...
                    app.modelInfo.FeedbackTimes, 'UniformOutput', false);
                timeList = strjoin(timeStrs, ', ');
                msg = sprintf('模型部署完成! 包含%d个时间点: %s', ...
                    app.modelInfo.ModelCount, timeList);
                app.updateStatus(msg,'system');
                uialert(app.UIFigure, msg, '成功', 'Icon', 'success');

                % 禁用相关控件
                app.Button_TrainModel.Enable='off';
                app.ModelSelect.Enable='off';
                app.EditField_T.Enable='off';
                app.EditField_F1.Enable='off';
                app.EditField_F2.Enable='off';

            else % "关闭"状态
                if ~isempty(app.Model)
                    selection = uiconfirm(app.UIFigure, ...
                        '是否清除缓存的分类模型？', '清除模型', ...
                        'Options', {'是', '否'}, ...
                        'DefaultOption', 2, 'CancelOption', 2);
                    if strcmp(selection, '是')
                        app.Model = [];
                        app.modelInfo = [];
                        app.updateStatus('模型已清除！','system')
                    end
                end

                % 更新模型准备状态
                app.modelReady = false;

                % 更改显示状态
                app.Button_ApplyModel.Text='部署模型';
                app.Lamp_ModelReady.Color = [1, 0, 0]; % 红色

                % 启用相关控件
                app.Button_TrainModel.Enable='on';
                app.ModelSelect.Enable='on';
                app.EditField_T.Enable='on';
                app.EditField_F1.Enable='on';
                app.EditField_F2.Enable='on';
                
            end
        end

        % Value changed function: Button_Impedances
        function Button_ImpedancesValueChanged(app, event)
            value = app.Button_Impedances.Value;
            
            %开启/关闭 检查阻抗
            if value
                % 启动 NeuroScanClient TCP 连接
                try
                    hostIP = app.LocalIP.Value;         % 本地 IP 
                    portNum = app.ScanPort.Value;       % Scan端口号
                    app.NSClient.startImp(hostIP, portNum);
                catch ME
                    app.handleError(ME, 'TCP 连接连接失败')
                    app.Button_Impedances.Value = false;
                    return;
                end
                
                % 禁用相关控件
                app.LocalIP.Enable='off';
                app.ScanPort.Enable='off';
                app.Button_Tcp.Enable='off';

                app.updateStatus('阻抗显示已开启!','success')
            else
                % 关闭TCP连接
                app.NSClient.stopImp();

                % 启用相关控件
                app.LocalIP.Enable='on';
                app.ScanPort.Enable='on';
                app.Button_Tcp.Enable='on';

                app.updateStatus('阻抗显示已关闭!','system')
            end
        end

        % Value changed function: LocalIP
        function LocalIPValueChanged(app, event)
            value = app.LocalIP.Value;
            app.check_IPAddress(value);
        end

        % Value changed function: RemoteHost
        function RemoteHostValueChanged(app, event)
            value = app.RemoteHost.Value;
            app.check_IPAddress(value);
        end

        % Value changed function: ScanPort
        function ScanPortValueChanged(app, event)
            value = app.ScanPort.Value;
            if value <1 || value >65536
                errordlg({'超出范围（1-65536）','请重新输入'},'输入错误')
                app.ScanPort.Value=4000;
            end
        end

        % Value changed function: LocalPort
        function LocalPortValueChanged(app, event)
            value = app.LocalPort.Value;
            if value <1 || value >65536
                uialert(app.UIFigure, {'超出范围（1-65536）','请重新输入'}, ...
                    '输入错误');
                app.LocalPort.Value=9095;
            end
        end

        % Value changed function: RemotePort
        function RemotePortValueChanged(app, event)
            value = app.RemotePort.Value;
            if value <1 || value >65536
                uialert(app.UIFigure, {'超出范围（1-65536）','请重新输入'}, ...
                    '输入错误');
                app.RemotePort.Value=9094;
            end
        end

        % Value changed function: EditField_T
        function EditField_TValueChanged(app, event)
            value = app.EditField_T.Value;
            if value>app.ConfigMgr.TimePoints(1)
                value = 0;
                uialert(app.UIFigure, '时间偏移量不能大于最小反馈时长！','输入错误');
            end
            app.EditField_T.Value = round(value,1);%精度保留到小数点后1位数
        end

        % Value changed function: EditField_F1
        function EditField_F1ValueChanged(app, event)
            value = app.EditField_F1.Value;
            app.EditField_F1.Value = round(value);%精度保留到整数
        end

        % Value changed function: EditField_F2
        function EditField_F2ValueChanged(app, event)
            value = app.EditField_F2.Value;
            if value<=app.EditField_F1.Value
                value=app.EditField_F1.Value+8;
                uialert(app.UIFigure, '终止频率应当大于起始频率！','输入错误');
            end
            app.EditField_F2.Value = round(value);%精度保留到整数
        end

        % Callback function
        function SaveConfigMenuSelected(app, event)
            % 获取保存路径
            configDir = app.ConfigMgr.ConfigFolder;
            [file, path] = uiputfile(fullfile(configDir, '*.mat'), ...
                '保存配置文件');
            
            if isequal(file, 0)
                return; % 用户取消
            end
            
            try
                % 保存配置
                app.ConfigMgr.saveToFile(fullfile(path, file));
                app.updateStatus(sprintf('配置已保存到: %s', file),'config');
                
            catch ME
                app.handleError(ME, '保存配置失败');
                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ModifyDataDirectory
        function Menu_ModifyDataDirectorySelected(app, event)
            sel = uigetdir(app.FileMgr.RootFolder, '选择保存 EEG 数据根目录');
            if isequal(sel,0)
                return;
            end
            app.FileMgr.setRootFolder(sel);
            app.updateStatus('已设置 EEG 数据根目录','config')
        end

        % Menu selected function: Menu_ViewDataList
        function Menu_ViewDataListSelected(app, event)
            app.FileMgr.initDataBuffer(app.ConfigMgr);
            app.FileMgr.showDataListDialog();
        end

        % Menu selected function: Menu_BatchOrganizeData
        function Menu_BatchOrganizeDataSelected(app, event)
            app.FileMgr.initDataBuffer(app.ConfigMgr);
            app.FileMgr.showMergeDialog();
        end

        % Menu selected function: Menu_LoadConfig
        function Menu_LoadConfigSelected(app, event)
            % 获取配置文件路径
            configDir = app.ConfigMgr.ConfigFolder;
            [file, path] = uigetfile(fullfile(configDir, '*.mat'), ...
                '选择配置文件');
            
            if isequal(file, 0)
                return; % 用户取消
            end
            
            try
                % 加载配置
                app.ConfigMgr.loadConfig(fullfile(path, file));
                
                % 重新初始化数据缓存
                app.FileMgr.initDataBuffer(app.ConfigMgr);
                app.initDataBuffer();
                
                % 更新状态
                app.updateStatus('配置已成功加载并应用','config');
                
            catch ME
                app.handleError(ME, '加载配置失败');
                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ViewConfig
        function Menu_ViewConfigSelected(app, event)
            % 显示当前配置
            configStr = app.ConfigMgr.getConfigString();
            
            % 在对话框中显示
            uialert(app.UIFigure, configStr, '当前配置', ...
                'Icon', 'info');

            % app.ConfigMgr.showConfigDialog();
        end

        % Menu selected function: Menu_ModifyConfigMenu
        function Menu_ModifyConfigMenuSelected(app, event)
            app.ConfigMgr.openEditDialog();
        end

        % Menu selected function: Menu_DefaultConfigMenu
        function Menu_DefaultConfigMenuSelected(app, event)
           
            % 恢复默认配置
            app.ConfigMgr = ConfigManager();
            
            % 重新初始化数据缓存
            app.FileMgr.initDataBuffer(app.ConfigMgr);
            app.initDataBuffer();
            
            % 更新状态
            app.updateStatus('已恢复默认配置','config');
        end

        % Menu selected function: Menu_Manual
        function Menu_ManualSelected(app, event)
            % 调用外部函数创建用户手册对话框
            createManualDialog();
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            % 调用外部函数创建关于对话框
            createAboutDialog();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '采集端操作界面';
            app.MenuFile = uimenu(app.UIFigure);
            app.MenuFile.Text = '文件';
            app.Menu_ModifyDataDirectory = uimenu(app.MenuFile);
            app.Menu_ModifyDataDirectory.MenuSelectedFcn = createCallbackFcn(app, @Menu_ModifyDataDirectorySelected, true);
            app.Menu_ModifyDataDirectory.Text = '修改数据根目录';
            app.Menu_ViewDataList = uimenu(app.MenuFile);
            app.Menu_ViewDataList.MenuSelectedFcn = createCallbackFcn(app, @Menu_ViewDataListSelected, true);
            app.Menu_ViewDataList.Text = '查看当前数据列表';
            app.Menu_BatchOrganizeData = uimenu(app.MenuFile);
            app.Menu_BatchOrganizeData.MenuSelectedFcn = createCallbackFcn(app, @Menu_BatchOrganizeDataSelected, true);
            app.Menu_BatchOrganizeData.Text = '批量整理数据';
            app.MenuConfig = uimenu(app.UIFigure);
            app.MenuConfig.Text = '配置';
            app.Menu_LoadConfig = uimenu(app.MenuConfig);
            app.Menu_LoadConfig.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadConfigSelected, true);
            app.Menu_LoadConfig.Text = '加载配置';
            app.Menu_ViewConfig = uimenu(app.MenuConfig);
            app.Menu_ViewConfig.MenuSelectedFcn = createCallbackFcn(app, @Menu_ViewConfigSelected, true);
            app.Menu_ViewConfig.Text = '查看当前配置';
            app.Menu_ModifyConfigMenu = uimenu(app.MenuConfig);
            app.Menu_ModifyConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @Menu_ModifyConfigMenuSelected, true);
            app.Menu_ModifyConfigMenu.Text = '修改当前配置';
            app.Menu_DefaultConfigMenu = uimenu(app.MenuConfig);
            app.Menu_DefaultConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @Menu_DefaultConfigMenuSelected, true);
            app.Menu_DefaultConfigMenu.Text = '恢复默认配置';
            app.MenuHelp = uimenu(app.UIFigure);
            app.MenuHelp.Text = '帮助';
            app.Menu_Manual = uimenu(app.MenuHelp);
            app.Menu_Manual.MenuSelectedFcn = createCallbackFcn(app, @Menu_ManualSelected, true);
            app.Menu_Manual.Text = '用户手册';
            app.Menu_About = uimenu(app.MenuHelp);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '反馈输出';
            app.Lamp_UdpReady = uilamp(app.Panel_3);
            app.IPLabel_2 = uilabel(app.Panel_3);
            app.IPLabel_2.Text = '反馈端IP';
            app.RemoteHost = uieditfield(app.Panel_3, 'text');
            app.RemoteHost.ValueChangedFcn = createCallbackFcn(app, @RemoteHostValueChanged, true);
            app.RemoteHost.Value = '169.254.81.94';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '端口';
            app.LocalPort = uieditfield(app.Panel_3, 'numeric');
            app.LocalPort.ValueChangedFcn = createCallbackFcn(app, @LocalPortValueChanged, true);
            app.LocalPort.Value = 9095;
            app.RemotePort = uieditfield(app.Panel_3, 'numeric');
            app.RemotePort.ValueChangedFcn = createCallbackFcn(app, @RemotePortValueChanged, true);
            app.RemotePort.Value = 9094;
            app.Label_Arrow = uilabel(app.Panel_3);
            app.Label_Arrow.Text = '→';
            app.Button_Udp = uibutton(app.Panel_3, 'state');
            app.Button_Udp.ValueChangedFcn = createCallbackFcn(app, @Button_UdpValueChanged, true);
            app.Button_Udp.Text = '开启连接';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '信号处理';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '~';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '频率范围';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '时间偏移量';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '分类模型';
            app.EditField_F2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_F2.Limits = [1 50];
            app.EditField_F2.ValueDisplayFormat = '%.0f';
            app.EditField_F2.ValueChangedFcn = createCallbackFcn(app, @EditField_F2ValueChanged, true);
            app.EditField_F2.Value = 30;
            app.EditField_F1 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_F1.Limits = [1 50];
            app.EditField_F1.ValueDisplayFormat = '%.0f';
            app.EditField_F1.ValueChangedFcn = createCallbackFcn(app, @EditField_F1ValueChanged, true);
            app.EditField_F1.Value = 8;
            app.EditField_T = uieditfield(app.Panel_2, 'numeric');
            app.EditField_T.Limits = [0 5];
            app.EditField_T.ValueChangedFcn = createCallbackFcn(app, @EditField_TValueChanged, true);
            app.ModelSelect = uidropdown(app.Panel_2);
            app.ModelSelect.Items = {'CSP', 'FBCSP', 'FgMDM', 'TSM', 'TRCA', 'DCPM', 'SBLEST', 'CTSSP', 'Ensemble', 'Stacking', 'RSFDA'};
            app.ModelSelect.Value = 'CSP';
            app.Lamp_ModelReady = uilamp(app.Panel_2);
            app.Button_ApplyModel = uibutton(app.Panel_2, 'state');
            app.Button_ApplyModel.ValueChangedFcn = createCallbackFcn(app, @Button_ApplyModelValueChanged, true);
            app.Button_ApplyModel.Text = '部署模型';
            app.Button_EvaluationModel = uibutton(app.Panel_2, 'push');
            app.Button_EvaluationModel.ButtonPushedFcn = createCallbackFcn(app, @Button_EvaluationModelPushed, true);
            app.Button_EvaluationModel.Text = '模型评估';
            app.Button_TrainModel = uibutton(app.Panel_2, 'push');
            app.Button_TrainModel.ButtonPushedFcn = createCallbackFcn(app, @Button_TrainModelPushed, true);
            app.Button_TrainModel.Text = '训练模型';
            app.Button_FeatureAnalysis = uibutton(app.Panel_2, 'push');
            app.Button_FeatureAnalysis.ButtonPushedFcn = createCallbackFcn(app, @Button_FeatureAnalysisPushed, true);
            app.Button_FeatureAnalysis.Text = '特征分析';
            app.Button_Loaddata = uibutton(app.Panel_2, 'push');
            app.Button_Loaddata.ButtonPushedFcn = createCallbackFcn(app, @Button_LoaddataPushed, true);
            app.Button_Loaddata.Text = '加载数据';
            app.Panel_1 = uipanel(app.UIFigure);
            app.Panel_1.Title = '信号输入';
            app.Lamp_TcpReady = uilamp(app.Panel_1);
            app.Label = uilabel(app.Panel_1);
            app.Label.Text = '端口';
            app.ScanPort = uieditfield(app.Panel_1, 'numeric');
            app.ScanPort.ValueChangedFcn = createCallbackFcn(app, @ScanPortValueChanged, true);
            app.ScanPort.Value = 4000;
            app.IPLabel = uilabel(app.Panel_1);
            app.IPLabel.Text = '采集端IP';
            app.LocalIP = uieditfield(app.Panel_1, 'text');
            app.LocalIP.ValueChangedFcn = createCallbackFcn(app, @LocalIPValueChanged, true);
            app.LocalIP.Editable = 'off';
            app.LocalIP.Value = '169.254.81.94';
            app.Button_Impedances = uibutton(app.Panel_1, 'state');
            app.Button_Impedances.ValueChangedFcn = createCallbackFcn(app, @Button_ImpedancesValueChanged, true);
            app.Button_Impedances.Text = '阻抗';
            app.Button_Tcp = uibutton(app.Panel_1, 'state');
            app.Button_Tcp.ValueChangedFcn = createCallbackFcn(app, @Button_TcpValueChanged, true);
            app.Button_Tcp.Text = '开启连接';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.Image_Logo = uiimage(app.UIFigure);
            app.Label_AppName = uilabel(app.UIFigure);
            app.Label_AppName.Text = '脑机接口在线识别与反馈系统';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquisitionClient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end