classdef StimulusProgram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AuthorLabel           matlab.ui.control.Label
        Label_AppName         matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        TextArea              matlab.ui.control.TextArea
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Button_Offline        matlab.ui.control.Button
        DropDown_TaskMode     matlab.ui.control.DropDown
        EditField_RestTime    matlab.ui.control.NumericEditField
        EditField_CueTime     matlab.ui.control.NumericEditField
        EditField_TrialNum    matlab.ui.control.NumericEditField
        DropDown_LabelPort    matlab.ui.control.DropDown
        DropDown_OfflineMode  matlab.ui.control.DropDown
        Label_5               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        Label_8               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Tab_2                 matlab.ui.container.Tab
        Button_Udp            matlab.ui.control.StateButton
        Button_Online         matlab.ui.control.StateButton
        IPEditFieldLabel_2    matlab.ui.control.Label
        LocalHost             matlab.ui.control.EditField
        Label_6               matlab.ui.control.Label
        LocalPort             matlab.ui.control.NumericEditField
        EditFieldLabel_2      matlab.ui.control.Label
        RemotePort            matlab.ui.control.NumericEditField
        RemoteHost            matlab.ui.control.EditField
        FeedbackDuration      matlab.ui.control.NumericEditField
        DropDown_OnlineMode   matlab.ui.control.DropDown
        IPEditFieldLabel      matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        Label_Arrow           matlab.ui.control.Label
        IPEditFieldLabel_3    matlab.ui.control.Label
    end

 
    properties (Access = private)

        %================ 状态显示相关  ================= 
        statusHistory = {};           % 存储历史状态信息
        maxStatusLines = 100;         % 历史记录最大长度
        classtype = {'左手划动','右手划动','双手划动'}

        %================ UDP通信/反馈 =================
        Host_IPAdr                    % 本地IP
        udp_ctrl                      % udp接口
        robot                         % Java机器人
        autoTimer                     % 异步开始实验范式程序定时器
        calibrate = true;             % 是否自动确定反馈类型

        result                        % 在线实验反馈结果
    end
    
    methods (Access = public)
        
        % 初始化Java机器人
        function initJavaRobot(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.robot = java.awt.Robot;
        end

        function check_IPAddress(app, value)
            flag=false;
            strlegal=true;
            %检查是否存在 0-9 和 . 以外的字符
            for i=1:length(value)
                if (value(i)>'9'||value(i)<'0')&&value(i)~='.'
                    errordlg({['存在非法字符' value(i)];'请重新输入'},'输入错误')
                    strlegal=false;
                    flag=true;
                    break;
                end
            end

            if strlegal
                substr=regexp(value,'\.','split');%将IP地址4个字节拆开
                if length(substr)==4              %是否4个字节
                    for i=1:4
                        if strcmp(substr{i},'')   %是否存在空字符
                            errordlg({'不是合法的IP地址格式（例如192.168.1.101）';'请重新输入'},'输入错误')
                            flag=true;
                        else
                            if str2double(substr{i})<0||str2double(substr{i})>255   %数字是否超出范围
                                errordlg({[substr{i} ' 超出范围（0-255）'];'请重新输入'},'输入错误')
                                flag=true;
                                break;
                            end
                        end
                    end
                else
                    errordlg({'不是合法的IP地址格式（例如192.168.1.101）';'请重新输入'},'输入错误')
                    flag=true;
                end
            end

            if flag
                if strcmpi(value,app.RemoteHost.Value)
                    app.RemoteHost.Value='169.254.81.94';
                elseif strcmpi(value,app.LocalHost.Value)
                    app.LocalHost.Value=app.Host_IPAdr;
                end
            end
        end
        
        % 更新状态显示
        function updateStatus(app, message, type)
            % 获取当前时间戳
            timestamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            
            % 确定消息类型和颜色
            if nargin < 3
                type = 'info';
            end

            % 颜色映射
            typeLabels = struct(...
                'info',    '实验信息', ...   % 信息
                'config',  '配置信息', ...   % 配置
                'success', '成功提醒', ...   % 正确
                'warning', '警告提醒', ...   % 警告
                'error',   '错误提醒', ...   % 错误
                'system',  '系统消息');      % 系统
            typeCell = typeLabels.(lower(type));
           
            % 创建带格式的消息行
            formattedLine = sprintf('[%s] %s : %s', timestamp, typeCell, message);

            % 添加到历史记录
            app.statusHistory{end+1} = formattedLine;
            
            % 限制历史记录长度
            if numel(app.statusHistory) > app.maxStatusLines
                app.statusHistory(1) = [];
            end
            
            % 更新文本区域显示
            app.TextArea.Value = app.statusHistory;
            
            % 自动滚动到底部
            scroll(app.TextArea, 'bottom');
            drawnow; 
           
        end

        % 错误处理
        function handleError(app, ME, context)
            % 生成错误消息
            errMsg = sprintf('%s: %s', context, ME.message);
            
            % 更新状态显示
            app.updateStatus(errMsg, 'error');
            
            % 可选：在对话框中显示详细错误
            uialert(app.UIFigure, errMsg, '错误', 'Icon', 'error');
        end

        % 处理UDP数据
        function processUDPData(app, src)
            try
                % 读取2字节数据
                data = read(src, 2, "uint8");
                
                % 协议处理
                switch data(1)
                    case 255  % 控制指令
                        app.handleControlCommand(data(2));
                        
                    case 254  % 数据包
                        app.handleDataPacket(data(2));
                end
            catch ME
                warning(['数据处理错误: %s', ME.message]);
            end
        end
        
        % 处理控制指令
        function handleControlCommand(app, command)
            switch command
                case 1 % 收到连接请求
                    % 发送连接确认[255, 2]
                    write(app.udp_ctrl, uint8([255, 2]), "uint8", ...
                        app.udp_ctrl.RemoteHost, app.udp_ctrl.RemotePort);
                    app.updateStatus('收到连接请求，已发送确认','system');
                case 2 % 收到连接确认
                    app.updateStatus('连接成功！','system');
                case 3 % 开始实验请求
                    % ...
                    
                case 0 % 收到断开通知
                    app.updateStatus('收到断开连接通知','system');
                    app.shutdownReceiver(false);  % 被动关闭
                    
                otherwise
                    warning('未知控制指令: %d', command);
            end
        end
        
        % 处理数据包
        function handleDataPacket(app, value)
            predlabel = app.getDigit(value, 1);
            truelabel = app.getDigit(value, 2);
            timepoint = app.getDigit(value, 3);

            % 记录在线反馈结果
            app.result = cat(1, app.result, [timepoint, ...
                truelabel, predlabel]);

            % 显示反馈指令
            msg=sprintf('接收到反馈控制指令: %d (%s)', predlabel, ...
                app.classtype{predlabel});
            app.updateStatus(msg)

            % 执行控制命令
            if timepoint==3
                app.executeControlCommand();
            end
        end
        
        % 获取数值的各位数
        function digit = getDigit(~, num, n)
            digit = floor(mod(num, 10^n) / 10^(n-1));
        end

        % 执行控制命令
        function executeControlCommand(app)
            if app.calibrate
                repeatNum = sum(app.result(end-2:end,2)==app.result(end-2:end,3));
                if repeatNum < 1
                    return;
                end
                sound(sin(2*pi*5*(1:4000)/100));
                
                % 确定反馈时长和反馈类型
                duration = app.FeedbackDuration.Value * repeatNum;
                duration = min(duration, app.EditField_RestTime.Value);
                type = DetectDirection;
            else
                duration = app.FeedbackDuration.Value;
                type = app.result(end, 3);
            end
            
            if ismember(type, [1, 2])
                app.feedback(type, duration);
            else
                app.feedback(mode(app.result(:,2)), duration);
            end
        end

        % 反馈控制动作列表
        function feedback(app, type, duration)
            switch type
                case 1
                    app.robot.keyPress(java.awt.event.KeyEvent.VK_LEFT);
                    pause(duration);
                    app.robot.keyRelease(java.awt.event.KeyEvent.VK_LEFT);
                case 2
                    app.robot.keyPress(java.awt.event.KeyEvent.VK_RIGHT);
                    pause(duration);
                    app.robot.keyRelease(java.awt.event.KeyEvent.VK_RIGHT);
                case 3
                    pause(duration);
                case 4
                    app.robot.keyPress    (java.awt.event.KeyEvent.VK_LEFT);
                    app.robot.keyPress    (java.awt.event.KeyEvent.VK_RIGHT);
                    pause(duration);
                    app.robot.keyRelease  (java.awt.event.KeyEvent.VK_LEFT);
                    app.robot.keyRelease  (java.awt.event.KeyEvent.VK_RIGHT);
            end
        end

        % 自动实验模式
        function runAutoExperiment(app)
            RestTime=app.EditField_RestTime.Value;
            CueTime=app.EditField_CueTime.Value;
            TrialNum=app.EditField_TrialNum.Value;
            Address = app.DropDown_LabelPort.Value;

            % 选择在线实验范式
            switch app.DropDown_OnlineMode.Value
                case 'game_auto'
                    % 使用定时器执行自动实验
                    app.autoTimer = timer(...
                        'TimerFcn', @(~,~) stimulate_auto(0, ...
                                    CueTime, RestTime, TrialNum, Address), ...
                        'StartDelay', 1, ...
                        'ExecutionMode', 'singleShot'); 
                case 'game'
                    TaskMode=str2double(app.DropDown_TaskMode.Value);
                    app.autoTimer = timer(...
                        'TimerFcn', @(~,~) stimulate_auto(TaskMode, ...
                                    CueTime, RestTime, TrialNum, Address), ...
                        'StartDelay', 1, ...
                        'ExecutionMode', 'singleShot'); 
            end
            % 启动计时器
            start(app.autoTimer);
        end

        % 结束实验的统一处理
        function endExperiment(app)
            % 确保在主线程执行UI更新
            if isvalid(app.UIFigure)
                % 更新按钮状态
                app.Button_Online.Value = false;
                app.Button_Online.Text = '开始实验';
                
                % 启用相关控件
                app.FeedbackDuration.Enable = 'on';
                app.DropDown_OnlineMode.Enable = 'on';
            end
            
            % 更新状态
            app.updateStatus('实验已结束');
        end

        % 关闭接收端
        function shutdownReceiver(app, isActive)
            % 清理UDP资源
            app.cleanupUDP();
            
            % 清理Java机器人
            if isprop(app, 'robot') && ~isempty(app.robot)
                app.robot = [];
            end
            
            % 更改显示状态
            app.Lamp.Color = [1 0 0];
            app.Button_Udp.Text='开启连接';
            
            % 启用相关控件
            app.RemoteHost.Enable = 'on';
            app.FeedbackDuration.Enable = 'on';
            app.LocalPort.Enable = 'on';
            app.RemotePort.Enable = 'on';
            app.Button_Online.Enable = 'off';
           
            if isActive
                app.updateStatus('通信接收已主动关闭','system')
            else
                app.Button_Udp.Value=false;
                app.updateStatus('通信接收已被动关闭','system')
            end
        end

        % 清理UDP资源
        function cleanupUDP(app)
            if ~isempty(app.udp_ctrl) && isvalid(app.udp_ctrl)
                try
                    % 停止回调
                    configureCallback(app.udp_ctrl, "off");
                    pause(0.1);
                    
                    % 删除对象
                    delete(app.udp_ctrl);
                catch
                end
                app.udp_ctrl = [];
            end
            
            % 清理自动实验定时器
            if isprop(app, 'autoTimer') && ~isempty(app.autoTimer) && isvalid(app.autoTimer)
                try
                    stop(app.autoTimer);
                    delete(app.autoTimer);
                catch
                end
                app.autoTimer = [];
            end
        end

        % 错误处理
        function handleUDPError(app, ME, context)
            app.cleanupUDP();
            msg=sprintf('%s: %s', context, ME.message);
            app.handleError(msg,'error')
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %添加辅助函数到工作路径
            fullpath = mfilename('fullpath');
            path=fileparts(fullpath);
            addpath(genpath(path));
            
            % 自动切换到脚本所在目录
            cd(path);

            % 置顶APP界面
            app.UIFigure.WindowStyle = 'alwaysontop';

            %显示logo
            app.UIFigure.Icon='Resources\Icons\icon_48.png';
            app.Image.ImageSource='Resources\Icons\logo.png';
            
            %获取本机IP地址
            address = java.net.InetAddress.getLocalHost;
            app.Host_IPAdr = char(address.getHostAddress);
            app.LocalHost.Value=app.Host_IPAdr;

            % 初始化状态显示
            app.updateStatus('欢迎使用运动想象脑-机接口在线实验范式系统!', 'system');
            app.updateStatus('本系统专为LC.Pan定制，其他人仅供参考学习!', 'system');
        end

        % Button pushed function: Button_Offline
        function Button_OfflinePushed(app, event)
            TaskMode=str2double(app.DropDown_TaskMode.Value);
            RestTime=app.EditField_RestTime.Value;
            CueTime=app.EditField_CueTime.Value;
            TrialNum=app.EditField_TrialNum.Value;
            Adress=app.DropDown_LabelPort.Value;
            
            app.DropDown_TaskMode.Enable='off';
            app.EditField_RestTime.Enable='off';
            app.EditField_CueTime.Enable='off';
            app.EditField_TrialNum.Enable='off';
            app.Button_Offline.Enable='off';
            app.DropDown_LabelPort.Enable='off';
            app.DropDown_OfflineMode.Enable='off';
            
            %% 执行MI心理任务范式
            switch app.DropDown_OfflineMode.Value
                case 'video'
                    % psyctoolbox
                    stimulate_plc(TaskMode,CueTime,RestTime,TrialNum,Adress)
                case 'game'
                    % NeuRow train mode
                    stimulate_auto(TaskMode,CueTime,RestTime,TrialNum,Adress)
                case 'game_auto'
                    % NeuRow online mode
                    stimulate_auto(0,CueTime,RestTime,TrialNum,Adress)
            end
            
            app.DropDown_TaskMode.Enable='on';
            app.EditField_RestTime.Enable='on';
            app.EditField_CueTime.Enable='on';
            app.EditField_TrialNum.Enable='on';
            app.Button_Offline.Enable='on';
            app.DropDown_LabelPort.Enable='on';
            app.DropDown_OfflineMode.Enable='on';
            
        end

        % Value changed function: Button_Online
        function Button_OnlineValueChanged(app, event)
            value = app.Button_Online.Value;
            if value
                % 禁用相关控件
                app.FeedbackDuration.Enable='off';
                app.DropDown_OnlineMode.Enable='off';
                app.Button_Online.Text='结束实验';

                % 初始化在线反馈结果记录
                app.result = [];
                
                % 运行在线实验范式
                try
                    app.runAutoExperiment();
                    tic;
                    app.updateStatus('实验已启动');
                catch ME
                    app.endExperiment();
                    errordlg(['实验启动失败: ' ME.message]);
                end

            else
                mintime=(app.EditField_CueTime.Value + app.EditField_RestTime.Value ...
                    + 0.5)*app.EditField_TrialNum.Value;
                if toc<mintime
                    % 显示取消进度
                    progressDlg = uiprogressdlg(app.UIFigure, 'Title', '处理中', ...
                        'Message', '正在结束实验进程，请勿移动鼠标');
                    tic;
                    while toc < 3
                        progressDlg.Value = min(toc / 3, 0.99);
                        drawnow;
                        mousemove(1, 1);
                        pause(0.1);
                    end
                    close(progressDlg);
                end

                % 执行结束操作
                app.endExperiment();
            end
        end

        % Value changed function: Button_Udp
        function Button_UdpValueChanged(app, event)
            value = app.Button_Udp.Value;
            if value

                % 清理现有资源
                app.cleanupUDP();

                try
                    % 创建UDP接收端口
                    app.udp_ctrl = udpport("IPV4", ...
                        'LocalPort', app.LocalPort.Value);

                    % 配置发送端IP和端口信息
                    app.udp_ctrl.RemoteHost = app.RemoteHost.Value;
                    app.udp_ctrl.RemotePort = app.RemotePort.Value;

                    % 配置回调函数 - 每次收到2字节触发
                    configureCallback(app.udp_ctrl, "byte", 2, ...
                        @(src,~) processUDPData(app, src));

                    % 初始化Java机器人
                    initJavaRobot(app);

                    app.updateStatus('UDP接收端已启动，等待连接...','system');
                catch ME
                    handleUDPError(app, ME, '接收端初始化失败');
                    app.Button_Udp.Value = false;
                    return;
                end
                
                % 更改显示状态
                app.Lamp.Color=[0 1 0];%绿色
                app.Button_Udp.Text='关闭连接';

                % 禁用相关控件
                app.RemoteHost.Enable = 'off';
                app.FeedbackDuration.Enable = 'off';
                app.LocalPort.Enable = 'off';
                app.RemotePort.Enable = 'off';
                app.Button_Online.Enable = 'on';

            else
                % 如果是主动关闭，发送断开通知
                if ~isempty(app.udp_ctrl) && isvalid(app.udp_ctrl)
                    write(app.udp_ctrl, uint8([255, 0]), "uint8", ...
                        app.udp_ctrl.RemoteHost, app.udp_ctrl.RemotePort);
                    pause(0.1); % 确保消息发送
                end

                % 关闭接收端
                app.shutdownReceiver(true);

            end
        end

        % Value changed function: DropDown_TaskMode
        function DropDown_TaskModeValueChanged(app, event)
            value = app.DropDown_TaskMode.Value;
            if value=='4'
                if mod(app.EditField_TrialNum.Value,3)~=0
                    app.EditField_TrialNum.Value=30;
                end
            end
        end

        % Value changed function: EditField_TrialNum
        function EditField_TrialNumValueChanged(app, event)
            value = app.EditField_TrialNum.Value;
            if app.DropDown_TaskMode.Value=='4' || app.DropDown_TaskMode.Value=='0'
                if mod(value,3)~=0
                    warndlg('请输入3的整倍数！','提示')
                    app.EditField_TrialNum.Value=value-mod(value,3);
                end
            elseif app.DropDown_TaskMode.Value=='3'
                if mod(value,2)~=0
                    warndlg('请输入2的整倍数！','提示')
                    app.EditField_TrialNum.Value=value-mod(value,2);
                end
            end
        end

        % Value changed function: DropDown_OnlineMode
        function DropDown_OnlineModeValueChanged(app, event)
            if ~exist('CalibrationSample.mat','file')
                GUI_Calibration;
            end
        end

        % Value changed function: DropDown_OfflineMode
        function DropDown_OfflineModeValueChanged(app, event)
            value = app.DropDown_OfflineMode.Value;
            if ~exist('CalibrationSample.mat','file') && ~strcmpi(value,'video')
                GUI_Calibration;
            end
        end

        % Value changed function: LocalHost
        function LocalHostValueChanged(app, event)
            value = app.LocalHost.Value;
            app.check_IPAddress(value);
        end

        % Value changed function: RemoteHost
        function RemoteHostValueChanged(app, event)
            value = app.RemoteHost.Value;
            app.check_IPAddress(value);
        end

        % Value changed function: LocalPort
        function LocalPortValueChanged(app, event)
            value = app.LocalPort.Value;
            if value <1 || value >65536
                errordlg({'超出范围（1-65536）','请重新输入'},'输入错误')
                app.LocalPort.Value=9094;
            end
        end

        % Value changed function: RemotePort
        function RemotePortValueChanged(app, event)
            value = app.RemotePort.Value;
            if value <1 || value >65536
                errordlg({'超出范围（1-65536）','请重新输入'},'输入错误')
                app.RemotePort.Value=9095;
            end
        end

        % Value changed function: FeedbackDuration
        function FeedbackDurationValueChanged(app, event)
            value = app.FeedbackDuration.Value;
            if value > app.EditField_RestTime.Value-1
                % 空闲时间包括了反馈时间+静息时间
                errordlg('安全起见，反馈时长应该至少比空闲时长少1秒！','输入错误');
                app.FeedbackDuration.Value = app.EditField_RestTime.Value-1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '反馈端操作界面';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '离线实验';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '任务类别';
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = '空闲时长';
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = '提示时长';
            app.Label_10 = uilabel(app.Tab);
            app.Label_10.Text = '试次数量';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '通信端口';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '实验模式';
            app.DropDown_OfflineMode = uidropdown(app.Tab);
            app.DropDown_OfflineMode.Items = {'video', 'game', 'game_auto'};
            app.DropDown_OfflineMode.ValueChangedFcn = createCallbackFcn(app, @DropDown_OfflineModeValueChanged, true);
            app.DropDown_OfflineMode.Value = 'video';
            app.DropDown_LabelPort = uidropdown(app.Tab);
            app.DropDown_LabelPort.Items = {'去标签测试', '并口57084', '并口20220', '并口888', '串口COM16'};
            app.DropDown_LabelPort.ItemsData = {'0', '57084', '20220', '888', 'COM16'};
            app.DropDown_LabelPort.Value = '0';
            app.EditField_TrialNum = uieditfield(app.Tab, 'numeric');
            app.EditField_TrialNum.Limits = [1 Inf];
            app.EditField_TrialNum.RoundFractionalValues = 'on';
            app.EditField_TrialNum.ValueDisplayFormat = '%.0f';
            app.EditField_TrialNum.ValueChangedFcn = createCallbackFcn(app, @EditField_TrialNumValueChanged, true);
            app.EditField_TrialNum.Tooltip = {'请选用分类数的倍数！'};
            app.EditField_TrialNum.Value = 30;
            app.EditField_CueTime = uieditfield(app.Tab, 'numeric');
            app.EditField_CueTime.Limits = [0 10];
            app.EditField_CueTime.ValueDisplayFormat = '%11.4gs';
            app.EditField_CueTime.Value = 4;
            app.EditField_RestTime = uieditfield(app.Tab, 'numeric');
            app.EditField_RestTime.Limits = [0 30];
            app.EditField_RestTime.ValueDisplayFormat = '%11.4gs';
            app.EditField_RestTime.Value = 4;
            app.DropDown_TaskMode = uidropdown(app.Tab);
            app.DropDown_TaskMode.Items = {'左手MI', '右手MI', '左/右手', '左/右/前'};
            app.DropDown_TaskMode.ItemsData = {'1', '2', '3', '4'};
            app.DropDown_TaskMode.ValueChangedFcn = createCallbackFcn(app, @DropDown_TaskModeValueChanged, true);
            app.DropDown_TaskMode.Value = '3';
            app.Button_Offline = uibutton(app.Tab, 'push');
            app.Button_Offline.ButtonPushedFcn = createCallbackFcn(app, @Button_OfflinePushed, true);
            app.Button_Offline.Text = '开始实验';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '在线实验';
            app.IPEditFieldLabel_3 = uilabel(app.Tab_2);
            app.IPEditFieldLabel_3.Text = '信号端口';
            app.Label_Arrow = uilabel(app.Tab_2);
            app.Label_Arrow.Text = '→';
            app.Lamp = uilamp(app.Tab_2);
            app.IPEditFieldLabel = uilabel(app.Tab_2);
            app.IPEditFieldLabel.Text = '采集端IP';
            app.DropDown_OnlineMode = uidropdown(app.Tab_2);
            app.DropDown_OnlineMode.Items = {'game', 'game_auto'};
            app.DropDown_OnlineMode.ValueChangedFcn = createCallbackFcn(app, @DropDown_OnlineModeValueChanged, true);
            app.DropDown_OnlineMode.Value = 'game';
            app.FeedbackDuration = uieditfield(app.Tab_2, 'numeric');
            app.FeedbackDuration.Limits = [0.1 10];
            app.FeedbackDuration.ValueDisplayFormat = '%11.4gs';
            app.FeedbackDuration.ValueChangedFcn = createCallbackFcn(app, @FeedbackDurationValueChanged, true);
            app.FeedbackDuration.Value = 1;
            app.RemoteHost = uieditfield(app.Tab_2, 'text');
            app.RemoteHost.ValueChangedFcn = createCallbackFcn(app, @RemoteHostValueChanged, true);
            app.RemoteHost.Value = '169.254.148.30';
            app.RemotePort = uieditfield(app.Tab_2, 'numeric');
            app.RemotePort.Limits = [0 65536];
            app.RemotePort.ValueDisplayFormat = '%.0f';
            app.RemotePort.ValueChangedFcn = createCallbackFcn(app, @RemotePortValueChanged, true);
            app.RemotePort.Value = 9095;
            app.EditFieldLabel_2 = uilabel(app.Tab_2);
            app.EditFieldLabel_2.Text = '反馈时长';
            app.LocalPort = uieditfield(app.Tab_2, 'numeric');
            app.LocalPort.Limits = [0 65536];
            app.LocalPort.ValueDisplayFormat = '%.0f';
            app.LocalPort.ValueChangedFcn = createCallbackFcn(app, @LocalPortValueChanged, true);
            app.LocalPort.Value = 9094;
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '实验模式';
            app.LocalHost = uieditfield(app.Tab_2, 'text');
            app.LocalHost.ValueChangedFcn = createCallbackFcn(app, @LocalHostValueChanged, true);
            app.LocalHost.Editable = 'off';
            app.LocalHost.Value = '192.168.1.100';
            app.IPEditFieldLabel_2 = uilabel(app.Tab_2);
            app.IPEditFieldLabel_2.Text = '反馈端IP';
            app.Button_Online = uibutton(app.Tab_2, 'state');
            app.Button_Online.ValueChangedFcn = createCallbackFcn(app, @Button_OnlineValueChanged, true);
            app.Button_Online.Enable = 'off';
            app.Button_Online.Text = '开始实验';
            app.Button_Udp = uibutton(app.Tab_2, 'state');
            app.Button_Udp.ValueChangedFcn = createCallbackFcn(app, @Button_UdpValueChanged, true);
            app.Button_Udp.Text = '开启连接';
            app.TextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Label_AppName = uilabel(app.UIFigure);
            app.Label_AppName.Text = '运动想象脑机接口在线实验范式系统';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = '@LC.Pan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StimulusProgram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end